classdef itest_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentalResultsTextAreaLabel  matlab.ui.control.Label
        Res                             matlab.ui.control.TextArea
        EstimatedNullDistributionLabel  matlab.ui.control.Label
        Null                            matlab.ui.control.TextArea
        StatisticalThresholdAlphaEditFieldLabel  matlab.ui.control.Label
        alpha                           matlab.ui.control.NumericEditField
        PrevalenceThresholdGamma_0EditFieldLabel  matlab.ui.control.Label
        g_0                             matlab.ui.control.NumericEditField
        ParameterforOrderStatisticiEditFieldLabel  matlab.ui.control.Label
        i                               matlab.ui.control.NumericEditField
        PvalueEditFieldLabel            matlab.ui.control.Label
        p                               matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Null, Res, alpha, g_0, i, p
        function ResValueChanged(app, event)
            [H, app.p.Value] = itest(reshape(str2num(app.Res.Value{1}),numel(str2num(app.Res.Value{1})),1),str2num(app.Null.Value{1}),app.i.Value,app.g_0.Value,app.alpha.Value);
            if H; app.p.FontColor=[1 0 0]; else; app.p.FontColor=[0 0 0]; end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExperimentalResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ExperimentalResultsTextAreaLabel.Text = 'Experimental Results';
            app.Res = uitextarea(app.UIFigure);
            app.Res.ValueChangedFcn = createCallbackFcn(app, @ResValueChanged, true);
            app.Res.Value = {'[0.48 0.56 0.6 0.61 0.65 0.69 0.72 0.77 0.8 0.83 0.91 0.93]'};
            app.EstimatedNullDistributionLabel = uilabel(app.UIFigure);
            app.EstimatedNullDistributionLabel.Text = 'Estimated Null Distribution';
            app.Null = uitextarea(app.UIFigure);
            app.Null.ValueChangedFcn = createCallbackFcn(app, @ResValueChanged, true);
            app.Null.Value = {'[0:0.01:1;binopdf(0:100,100,0.5)]'};
            app.StatisticalThresholdAlphaEditFieldLabel = uilabel(app.UIFigure);
            app.StatisticalThresholdAlphaEditFieldLabel.Text = 'Statistical Threshold (Alpha)';
            app.alpha = uieditfield(app.UIFigure, 'numeric');
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @ResValueChanged, true);
            app.alpha.Value = 0.05;
            app.PrevalenceThresholdGamma_0EditFieldLabel = uilabel(app.UIFigure);
            app.PrevalenceThresholdGamma_0EditFieldLabel.Text = 'Prevalence Threshold (Gamma_0)';
            app.g_0 = uieditfield(app.UIFigure, 'numeric');
            app.g_0.ValueChangedFcn = createCallbackFcn(app, @ResValueChanged, true);
            app.g_0.Value = 0.5;
            app.ParameterforOrderStatisticiEditFieldLabel = uilabel(app.UIFigure);
            app.ParameterforOrderStatisticiEditFieldLabel.Text = 'Parameter for Order Statistic (i)';
            app.i = uieditfield(app.UIFigure, 'numeric');
            app.i.ValueChangedFcn = createCallbackFcn(app, @ResValueChanged, true);
            app.i.Value = 3;
            app.PvalueEditFieldLabel = uilabel(app.UIFigure);
            app.PvalueEditFieldLabel.Text = 'P-value';
            app.p = uieditfield(app.UIFigure, 'numeric');
            app.p.ValueDisplayFormat = '%.4g';
            app.p.ValueChangedFcn = createCallbackFcn(app, @ResValueChanged, true);
            app.p.Editable = 'off';
            app.p.Value = 0.02435;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = itest_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end